classdef sldrt_apptest_2025b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProbeMenu                   matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        WaveFormTab                 matlab.ui.container.Tab
        SharpnessEditField          matlab.ui.control.NumericEditField
        SharpnessEditFieldLabel     matlab.ui.control.Label
        PacketNumEditField          matlab.ui.control.NumericEditField
        PacketNumEditFieldLabel     matlab.ui.control.Label
        PeriodsEditField            matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        GradTab                     matlab.ui.container.Tab
        AirUpdateButton             matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Res_umEditField             matlab.ui.control.NumericEditField
        ResmEditFieldLabel          matlab.ui.control.Label
        AirGradEditField            matlab.ui.control.NumericEditField
        AirgradEditFieldLabel       matlab.ui.control.Label
        CapturedCorrEditField       matlab.ui.control.NumericEditField
        CapturedCorrEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        MeanEditField               matlab.ui.control.NumericEditField
        MeanEditFieldLabel          matlab.ui.control.Label
        AirButton                   matlab.ui.control.Button
        ZeroButton                  matlab.ui.control.Button
        UI_LUT_Axes                 matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
        GradTableContextMenu        matlab.ui.container.ContextMenu
        ClearMenu                   matlab.ui.container.Menu
        AddMenu                     matlab.ui.container.Menu
        DeleteMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        UDPClient % Здесь будет храниться сетевой объект для отправки команд

        SLRTApp = 'sldrt_newtest_2025b'
        tg
        DataListener

        hSignalLine
        hEnvelopeLine
        hLUT_Line

        FromModelInstrument
        CodeAirGradInstrument
        InstrumentManager

        lhStarting
        lhStopped
        lhLoaded

        ParamTuner_Tau
        WaveForm

        Amplitude = 800
        Periods = 2
        Sharpness = 1

        Signal
        Code
        Kzero = 1.0 % code scale coeff (Nzero to 60000)
        AirGrad = 41000
        ObjCapt
        ObjCapt_mm
        ObjCaptFlag = false
        ProbeState
        ZeroBtnState = false
        AirBtnState = false
        UpdateAnimationValidCnt = 0
        UIGradTableFocus = false
        UIGradTableCurrIdx = 1

        %LUT_data

        LastSentSettings

        % звук ---------------
    AudioWriter         % Скоростной потоковый плеер
    SingleBeepSignal    % Готовый вектор для одиночного клика
    DoubleBeepSignal    % Готовый вектор для двойного клика
    SilentBeepSignal
    end

    properties (Access = public)
        LUT_data % тень градуировочной таблицы
        Settings % Все настройки (состояния кнопок, значения полей ввода, данные LUT и т.д. для отправки в модель)
    end


    methods (Access = private)
        function ManualUpdateCallback(app, src, event)
            if isvalid(app)

                app.UpdateAnimationValidCnt = app.UpdateAnimationValidCnt +1;

                if ~isequal(app.Settings, app.LastSentSettings)
                    packetData = app.Settings.toArray();
                    write(app.UDPClient, packetData, "double", "127.0.0.1", 25010);
                    app.LastSentSettings = app.Settings;
                end

                data = src.OutputPort(1).Data;

                app.Signal = data( 1:5000); %signal
                code = data( 5001:5002); %mean code
                app.Code = double( typecast( code, 'single'));

                app.AirGrad = double( typecast( data( 5003:5004), 'single'));
                app.Kzero = double( typecast( data( 5005:5006), 'single')); %code scale coeff (Nzero to 60000)
                ObjCaptF = boolean( typecast( data( 5007:5008), 'single'));
                app.ObjCapt = double( typecast( data( 5009:5010), 'single'));
                app.ProbeState = double( typecast( data( 5011:5012), 'single'));
                app.ObjCapt_mm = double( typecast( data( 5013:5014), 'single'));

                if isequal( app.TabGroup.SelectedTab.Title, 'WaveForm')
                    %s = double( app.Signal);
                    %sm = mean(s);
                    %s = s - sm;
                    %e = abs( hilbert(s));
                    %e = e + sm;

                    set( app.hSignalLine, 'YData', app.Signal);
                    %set( app.hEnvelopeLine, 'YData', e(1:5000));
                    drawnow limitrate;
                end

                app.AirGradEditField.Value = app.AirGrad;
                app.MeanEditField.Value = app.Code; % * K;

                o = app.UIFigure.CurrentObject;
                if ~isequal( o.Tag, 'UIGradTable')
                    app.UIGradTableFocus = false;
                end

                if ObjCaptF ~= app.ObjCaptFlag

                    %app.AudioWriter(app.SingleBeepSignal); 

                    app.CapturedCorrEditField.Value = app.ObjCapt;
                    app.Res_umEditField.Value = app.ObjCapt_mm;
                    app.ObjCaptFlag = ObjCaptF;

                    if app.UIGradTableFocus
                        n = cell2mat( app.LUT_data( app.UIGradTableCurrIdx, 3));
                        n = n +1;
                        app.LUT_data( app.UIGradTableCurrIdx, 3) = {n};

                        m = cell2mat( app.LUT_data( app.UIGradTableCurrIdx, 2));
                        m = ( m * ( n-1) + app.ObjCapt) /n;

                        app.LUT_data( app.UIGradTableCurrIdx, 2) = {m};
                        app.UITable.Data = app.LUT_data;

                        x = cell2mat( app.LUT_data(:, 1)); % толщина
                        y = cell2mat( app.LUT_data(:, 2)); % код
                        app.hLUT_Line.XData = x;
                        app.hLUT_Line.YData = y;

                        app.SendLUT();
                    end                    
                end


                switch app.ProbeState
                    case 7
                        app.Lamp.Color = 'Red';
                    case 2
                        app.Lamp.Color = 'Green';
                    otherwise
                        app.Lamp.Color = 'White';
                end

                app.PacketNumEditField.Value = app.UpdateAnimationValidCnt;
            end

        end


        function SetupAnimationAxes(app)
            %x = ( (0:4999)/5000*20).';
            x = (0:4999)';
            app.hSignalLine = plot( app.UIAxes, x, ones(5000, 1)*2000);
            hold( app.UIAxes, 'on');
            %app.hEnvelopeLine = plot( app.UIAxes, (0:4999).', ones(5000, 1)*2000);
            %plot( app.UIAxes, [0 20], [2048 2048]);
            plot( app.UIAxes, [0 4999], [2048 2048]);
            hold( app.UIAxes, 'off');
            ylim( app.UIAxes, [ -200 4200]);
            %xlim( app.UIAxes, [ 0 20]);
            xlim( app.UIAxes, [ 0 5000]);

            app.hLUT_Line = plot( app.UI_LUT_Axes, cell2mat( app.LUT_data(:, 1)), cell2mat( app.LUT_data(:, 2)), '-bo');
        end

        function cbTargetEventsListener(app, instObj, eventData)

            switch eventData.EventName
                case 'Loaded'
                    app.StartButton.Enable = "on";
                case 'Starting'
                case 'Stopped'
            end
        end

        function SendLUT(app)
            yy = cell2mat( app.LUT_data(:, 1))';
            xx = cell2mat( app.LUT_data(:, 2))';

            [ LUTxx, LUTyy, st] = UpdateLUT( 25, flip(xx), flip(yy));

            if st == 0
                app.Settings.xx = LUTxx;
                app.Settings.yy = LUTyy;

                setparam( app.tg, '', 'yy', LUTyy);
                setparam( app.tg, '', 'xx', LUTxx);
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Settings = FromApp();

            % Открываем UDP-порт на стороне компьютера
            app.UDPClient = udpport("LocalPort", 25015);

            app.LUT_data = table2cell( table( [0; 20; 51; 103; 154; 181; 232; 335; 466; 698; 976; 1465; 1964; 2440; 3121], ...
                [60000; 57862; 55663; 53185; 51343; 50678; 49334; 47769; 46388; 44717; 43533; 42429; 41973; 41682; 41344], ...
                [ 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0; 0]));
            app.UITable.Data = app.LUT_data;
            app.UITable.ColumnFormat = {'bank','bank','short'};

            app.AmplitudeEditField.Value = app.Amplitude;
            app.SharpnessEditField.Value = app.Sharpness;
            app.StartButton.Enable = "off";

            app.tg = slrealtime('Simulink Normal Mode');

            connect( app.tg);

            app.lhStarting = listener( app.tg, 'Starting', @app.cbTargetEventsListener);
            app.lhStopped = listener( app.tg, 'Stopped', @app.cbTargetEventsListener);
            app.lhLoaded = listener( app.tg, 'Loaded', @app.cbTargetEventsListener);

            app.SetupAnimationAxes();

            load( app.tg, app.SLRTApp);
            set_param(app.SLRTApp, 'BlockReduction', 'off');

            setparam( app.tg, '', 'AppPeriods', app.Periods);
            setparam(app.tg, '', 'AppAmplitude', app.Amplitude);
            setparam( app.tg, '', 'AppSharpness', app.Sharpness);

            app.SendLUT();

   % --- ИНИЦИАЛИЗАЦИЯ МГНОВЕННОГО ЗВУКА С НУЛЕВЫМ ЛАГОМ ---
    Fs = 44100;
    duration = 0.035; % Ультра-короткие 35 мс для хлесткого клика
    t = 0:(1/Fs):duration;
    beepTone = sin(2*pi*3000*t)'; % Ваш боевой тон 3 кГц

    % Сборка фиксированного окна (ровно 150 мс)
    totalSamples = double(Fs * 0.150); 

    % 0. Собираем Тишину
    app.SilentBeepSignal = zeros(totalSamples, 1);

    % 1. Собираем СИНГЛ
    app.SingleBeepSignal = zeros(totalSamples, 1);
    app.SingleBeepSignal(1:length(beepTone)) = beepTone;

    % 2. Собираем ДАБЛ (второй клик через 50 мс паузы)
    app.DoubleBeepSignal = zeros(totalSamples, 1);
    app.DoubleBeepSignal(1:length(beepTone)) = beepTone;
    startIdx2 = length(beepTone) + double(Fs * 0.050);
    endIdx2 = startIdx2 + length(beepTone) - 1;
    app.DoubleBeepSignal(startIdx2:endIdx2) = beepTone;

    % 3. Создаем системный плеер с минимальным буфером
    app.AudioWriter = audioDeviceWriter('SampleRate', Fs, 'BufferSize', 256);

    % 4. ДВОЙНОЙ КРИТИЧЕСКИЙ ПРОГРЕВ ДРАЙВЕРА:
    % Принудительно заставляем Windows выделить аудио-память СЕЙЧАС,
    % чтобы скрыть задержки подгрузки библиотек до открытия окон.
    setup(app.AudioWriter, zeros(totalSamples, 1));
    step(app.AudioWriter, zeros(totalSamples, 1));    
    %-------------------------------------------------------
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            %app.ZeroBtnState = false;
            %setparam( app.tg, '', 'AppZeroBtn', app.ZeroBtnState);
            %app.AirBtnState = false;
            %setparam( app.tg, '', 'AppAirBtn', app.AirBtnState);

            app.Settings.CmdZero = false;
            app.Settings.CmdAirGrad = false;
            app.Settings.CmdAirCurrent = false;


            st = app.tg.ModelStatus.State;
            if isequal( st, 'RUNNING')
                stop( app.tg);
                app.StartButton.Text = "Start";
            else
                start( app.tg);
                app.StartButton.Text = "Stop";
                app.DataListener = add_exec_event_listener( [app.SLRTApp, '/Rate Transition'], 'PostOutputs', @app.ManualUpdateCallback);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete( app.lhStarting);
            delete( app.lhStopped);
            delete( app.lhLoaded);

            % 1. Безопасно закрываем и освобождаем UDP-порт
            if ~isempty(app.UDPClient) && isvalid(app.UDPClient)
                clear app.UDPClient; % В MATLAB эта команда корректно закрывает системный сокет
            end

            % Удаляем слушатель данных
            if isfield(app, 'DataListener') && ~isempty(app.DataListener)
                delete(app.DataListener);
            end

            % Безопасно закрываем аудио-порт операционной системы
            if ~isempty(app.AudioWriter)
                release(app.AudioWriter);
            end
            
            disconnect( app.tg);
            delete(app)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.Settings.Amplitude = round( app.AmplitudeEditField.Value);
            app.Amplitude = round( app.AmplitudeEditField.Value);
            setparam(app.tg, '', 'AppAmplitude', app.Amplitude);
        end

        % Value changed function: PeriodsEditField
        function PeriodsEditFieldValueChanged(app, event)
            app.Settings.Periods = round( app.PeriodsEditField.Value);
            app.Periods = round( app.PeriodsEditField.Value);
            setparam( app.tg, '', 'AppPeriods', app.Periods);
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.Settings.CmdZero = ~app.Settings.CmdZero;
            %app.ZeroBtnState = ~app.ZeroBtnState;
            %setparam( app.tg, '', 'AppZeroBtn', app.ZeroBtnState);
        end

        % Button pushed function: AirButton
        function AirButtonPushed(app, event)
            app.Settings.CmdAirGrad = ~app.Settings.CmdAirGrad;
            %app.AirBtnState = ~app.AirBtnState;
            %setparam( app.tg, '', 'AppAirBtn', app.AirBtnState);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if isempty( indices)
                app.UIGradTableFocus = false;
            else
                app.UIGradTableCurrIdx = indices(1);
                app.UIGradTableFocus = true;
            end
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            if app.UIGradTableFocus
                app.LUT_data( app.UIGradTableCurrIdx, 2:3) = {0};
                app.UITable.Data = app.LUT_data;
            end
        end

        % Menu selected function: AddMenu
        function AddMenuSelected(app, event)
            [l, ~] = size( app.LUT_data);
            if l < 20
                T = cell2mat( app.LUT_data( l, 1));
                app.LUT_data( l +1, :) = {T, 0, 0};
                app.UITable.Data = app.LUT_data;
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            if app.UIGradTableFocus
                app.LUT_data( app.UIGradTableCurrIdx, :) = [];
                app.LUT_data = sortrows( app.LUT_data);
                app.UITable.Data = app.LUT_data;

                app.hLUT_Line.XData = cell2mat( app.LUT_data(:, 1));
                app.hLUT_Line.YData = cell2mat( app.LUT_data(:, 2));

                app.SendLUT();
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            app.UITable.Data = sortrows( app.UITable.Data);
            app.LUT_data = app.UITable.Data;

            app.hLUT_Line.XData = cell2mat( app.LUT_data(:, 1));
            app.hLUT_Line.YData = cell2mat( app.LUT_data(:, 2));

            app.SendLUT();
        end

        % Value changed function: SharpnessEditField
        function SharpnessEditFieldValueChanged(app, event)
            app.Settings.Sharpness = app.SharpnessEditField.Value;
            app.Sharpness = app.SharpnessEditField.Value;
            setparam( app.tg, '', 'AppSharpness', app.Sharpness);
        end

        % Button pushed function: AirUpdateButton
        function AirUpdateButtonPushed(app, event)
            app.Settings.CmdAirCurrent = ~app.Settings.CmdAirCurrent;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            NewProbe(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'K6-11 MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProbeMenu = uimenu(app.UIFigure);
            app.ProbeMenu.Text = 'Probe';
            app.NewMenu = uimenu(app.ProbeMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New...';
            app.OpenMenu = uimenu(app.ProbeMenu);
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.ProbeMenu);
            app.SaveMenu.Text = 'Save...';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveFormTab = uitab(app.TabGroup);
            app.WaveFormTab.Title = 'WaveForm';
            app.UIAxes = uiaxes(app.WaveFormTab);
            title(app.UIAxes, 'ADC')
            xlabel(app.UIAxes, 'ms')
            ylabel(app.UIAxes, 'Code')
            zlabel(app.UIAxes, 'Z')
            app.AmplitudeEditFieldLabel = uilabel(app.WaveFormTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.AmplitudeEditField.Limits = [0 4000];
            app.AmplitudeEditField.ValueDisplayFormat = '%.0f';
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 2000;
            app.PeriodsEditFieldLabel = uilabel(app.WaveFormTab);
            app.PeriodsEditFieldLabel.Text = 'Periods';
            app.PeriodsEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.PeriodsEditField.Limits = [1 50];
            app.PeriodsEditField.ValueDisplayFormat = '%.0f';
            app.PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsEditFieldValueChanged, true);
            app.PeriodsEditField.Value = 2;
            app.PacketNumEditFieldLabel = uilabel(app.WaveFormTab);
            app.PacketNumEditFieldLabel.Text = 'PacketNum';
            app.PacketNumEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.PacketNumEditField.ValueDisplayFormat = '%.0f';
            app.PacketNumEditField.Editable = 'off';
            app.SharpnessEditFieldLabel = uilabel(app.WaveFormTab);
            app.SharpnessEditFieldLabel.Text = 'Sharpness';
            app.SharpnessEditField = uieditfield(app.WaveFormTab, 'numeric');
            app.SharpnessEditField.Limits = [0 4];
            app.SharpnessEditField.ValueDisplayFormat = '%5.2f';
            app.SharpnessEditField.ValueChangedFcn = createCallbackFcn(app, @SharpnessEditFieldValueChanged, true);
            app.SharpnessEditField.Value = 1;
            app.GradTab = uitab(app.TabGroup);
            app.GradTab.Title = 'Grad';
            app.UI_LUT_Axes = uiaxes(app.GradTab);
            xlabel(app.UI_LUT_Axes, 'T')
            ylabel(app.UI_LUT_Axes, 'C')
            zlabel(app.UI_LUT_Axes, 'Z')
            app.ZeroButton = uibutton(app.GradTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.AirButton = uibutton(app.GradTab, 'push');
            app.AirButton.ButtonPushedFcn = createCallbackFcn(app, @AirButtonPushed, true);
            app.AirButton.Text = 'Air';
            app.MeanEditFieldLabel = uilabel(app.GradTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.GradTab, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.0f';
            app.MeanEditField.Editable = 'off';
            app.UITable = uitable(app.GradTab);
            app.UITable.ColumnName = {'T'; 'C'; 'Num'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Tag = 'UIGradTable';
            app.CapturedCorrEditFieldLabel = uilabel(app.GradTab);
            app.CapturedCorrEditFieldLabel.Text = 'Captured Corr';
            app.CapturedCorrEditField = uieditfield(app.GradTab, 'numeric');
            app.CapturedCorrEditField.ValueDisplayFormat = '%.0f';
            app.CapturedCorrEditField.Editable = 'off';
            app.AirgradEditFieldLabel = uilabel(app.GradTab);
            app.AirgradEditFieldLabel.Text = 'Air grad';
            app.AirGradEditField = uieditfield(app.GradTab, 'numeric');
            app.AirGradEditField.ValueDisplayFormat = '%.0f';
            app.ResmEditFieldLabel = uilabel(app.GradTab);
            app.ResmEditFieldLabel.Text = 'Res, µm';
            app.Res_umEditField = uieditfield(app.GradTab, 'numeric');
            app.Res_umEditField.ValueDisplayFormat = '%.0f';
            app.Res_umEditField.Editable = 'off';
            app.Lamp = uilamp(app.GradTab);
            app.AirUpdateButton = uibutton(app.GradTab, 'push');
            app.AirUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @AirUpdateButtonPushed, true);
            app.AirUpdateButton.Text = 'AirUpdate';
            app.GradTableContextMenu = uicontextmenu(app.UIFigure);
            app.ClearMenu = uimenu(app.GradTableContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.AddMenu = uimenu(app.GradTableContextMenu);
            app.AddMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMenuSelected, true);
            app.AddMenu.Text = 'Add';
            app.DeleteMenu = uimenu(app.GradTableContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.GradTableContextMenu
            app.UITable.ContextMenu = app.GradTableContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sldrt_apptest_2025b

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end